classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AyudaButton                     matlab.ui.control.Button
        HistorialButton                 matlab.ui.control.Button
        InicioButton                    matlab.ui.control.Button
        Image_4                         matlab.ui.control.Image
        Image_3                         matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        EstadodelaimagenLabel           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Panel                           matlab.ui.container.Panel
        RehacerButton                   matlab.ui.control.Button
        DeshacerButton                  matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        ExportarButton                  matlab.ui.control.Button
        ImportarButton                  matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        HerramientasdeRestauracinLabel  matlab.ui.control.Label
        AdicindeColorButton             matlab.ui.control.Button
        DegradacinButton                matlab.ui.control.Button
        ReconstruccinButton             matlab.ui.control.Button
        ManchasButton                   matlab.ui.control.Button
        ArrugasPlieguesButton           matlab.ui.control.Button
        RayonesButton                   matlab.ui.control.Button
        ImagenRestauradaLabel           matlab.ui.control.Label
        OriginalLabel                   matlab.ui.control.Label
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImagenOriginal  % Almacena la imagen importada
        ImagenProcesada % Almacena el resultado tras aplicar filtros/módulos

        % --- Pilas para Deshacer y Rehacer ---
        HistorialImagenes = {} % Celda para almacenar los estados de la imagen
        IndiceHistorial = 0    % Posición actual en el historial
    end
    
    methods (Access = private)
           % Guarda el estado actual en el historial
        function guardarEstado(app, nuevaImagen)
            % Elimina estados de rehacer si nos encontramos en medio del historial
            if app.IndiceHistorial < length(app.HistorialImagenes)
                app.HistorialImagenes = app.HistorialImagenes(1:app.IndiceHistorial);
            end
            
            % Agregar el nuevo estado y actualizar el índice
            app.IndiceHistorial = app.IndiceHistorial + 1;
            app.HistorialImagenes{app.IndiceHistorial} = nuevaImagen;
            app.ImagenProcesada = nuevaImagen;
        end
        
        % Actualiza los visores UIAxes_2 y UIAxes_4
        function actualizarInterfazProcesada(app)
            img = app.ImagenProcesada;
            
            % 1. Renderizar Imagen Procesada
            imshow(img, 'Parent', app.UIAxes_2);
            
            % 2. Renderizar Histograma en UIAxes_4
            if size(img, 3) == 3
                grisHist = rgb2gray(img);
            else
                grisHist = img;
            end
            
            [counts, binLocations] = imhist(grisHist);
            
            cla(app.UIAxes_4);
            bar(app.UIAxes_4, binLocations, counts, 'BarWidth', 1, 'FaceColor', [0 0.4470 0.7410], 'EdgeColor', 'none');
            app.UIAxes_4.XLim = [0 255];
            app.UIAxes_4.YLimMode = 'auto';
            app.UIAxes_4.YColor = [0.9 0.9 0.9];
            app.UIAxes_4.XColor = [0.9 0.9 0.9];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportarButton
        function ImportarButtonPushed(app, event)
            fileFilters = {
                '*.png;*.jpg;*.jpeg;*.tif;*.tiff;*.bmp', 'Archivos de imagen (*.png, *.jpg, *.jpeg, *.tif, *.bmp)'; ...
                '*.png',  'Imágenes PNG (*.png)'; ...
                '*.jpg;*.jpeg', 'Imágenes JPEG (*.jpg, *.jpeg)'; ...
                '*.tif;*.tiff', 'Imágenes TIFF (*.tif, *.tiff)'; ...
                '*.bmp',  'Imágenes Bitmap (*.bmp)'; ...
                '*.*',    'Todos los archivos (*.*)'};

            [fileName, folderPath] = uigetfile(fileFilters, 'Seleccionar imagen antigua');

            if isequal(fileName, 0) || isequal(folderPath, 0)
                return; % El usuario canceló la selección
            end
            try
                fullImagePath = fullfile(folderPath, fileName);
                img = imread(fullImagePath);
    
                app.ImagenOriginal = img;
                app.ImagenProcesada = img;
    
                imshow(img, 'Parent', app.UIAxes);
    
                app.EstadodelaimagenLabel.Text = sprintf('Estado: Importación exitosa (%s)', fileName);

            catch ME
                app.EstadodelaimagenLabel.Text = 'Estado: Fallo en la importación de la imagen.';
            end

        end

        % Button pushed function: RayonesButton
        function RayonesButtonPushed(app, event)
            if isempty(app.ImagenProcesada)
                uialert(app.UIFigure, 'Por favor, importe una imagen primero.', 'Falta Imagen');
                return;
            end
            
            app.EstadodelaimagenLabel.Text = 'Estado: Eliminando rayones...';
            drawnow;
            
            % 1. Obtener imagen actual
            img = app.ImagenProcesada;
            
            % Convertir a escala de grises para la máscara
            if size(img, 3) == 3
                grayImg = rgb2gray(img);
            else
                grayImg = img;
            end
            
            % 2. Top-Hat morfológico con un elemento estructurante más amplio (para rayones gruesos)
            se = strel('disk', 6); % Aumentado de 4 a 6
            tophatImg = imtophat(grayImg, se);
            
            % Umbral adaptativo basado en la mediana y desviación estándar del Top-Hat
            umbral = median(tophatImg(:)) + 1.8 * std(double(tophatImg(:)));
            mascaraRayones = tophatImg > umbral;
            
            % Engrosar la máscara para cubrir bordes desgastados del rayón
            mascaraRayones = imdilate(mascaraRayones, strel('disk', 2));
            
            % 3. Aplicar Inpainting (regionfill) sobre la máscara generada
            if size(img, 3) == 3
                imgRestaurada = img;
                for c = 1:3
                    imgRestaurada(:,:,c) = regionfill(img(:,:,c), mascaraRayones);
                end
            else
                imgRestaurada = regionfill(img, mascaraRayones);
            end
            
            % 4. Actualizar variable en proceso
            app.ImagenProcesada = imgRestaurada;
            
            % 5. Mostrar imagen procesada
            imshow(imgRestaurada, 'Parent', app.UIAxes_2); 
            
            % 6. Renderizar Histograma Restaurado en UIAxes_4
            if size(imgRestaurada, 3) == 3
                grisHist = rgb2gray(imgRestaurada);
            else
                grisHist = imgRestaurada;
            end
            
            [counts, binLocations] = imhist(grisHist);
            bar(app.UIAxes_4, binLocations, counts, 'BarWidth', 1, 'FaceColor', [0.8 0.8 0.8], 'EdgeColor', 'none');
            app.UIAxes_4.XLim = [0 255];
            app.UIAxes_4.YColor = [0.9 0.9 0.9];
            app.UIAxes_4.XColor = [0.9 0.9 0.9];
            
            % 7. Renderizar Histograma Original en UIAxes_3 si aún está vacío
            if size(app.ImagenOriginal, 3) == 3
                grisOrig = rgb2gray(app.ImagenOriginal);
            else
                grisOrig = app.ImagenOriginal;
            end
            [countsOrig, binLocOrig] = imhist(grisOrig);
            bar(app.UIAxes_3, binLocOrig, countsOrig, 'BarWidth', 1, 'FaceColor', [0.8 0.8 0.8], 'EdgeColor', 'none');
            app.UIAxes_3.XLim = [0 255];
            app.UIAxes_3.YColor = [0.9 0.9 0.9];
            app.UIAxes_3.XColor = [0.9 0.9 0.9];

            app.guardarEstado(imgRestaurada);
            app.actualizarInterfazProcesada();
            app.EstadodelaimagenLabel.Text = 'Estado: Rayones eliminados correctamente.';
        end

        % Button pushed function: ArrugasPlieguesButton
        function ArrugasPlieguesButtonPushed(app, event)
            if isempty(app.ImagenProcesada)
                uialert(app.UIFigure, 'Por favor, importe una imagen primero.', 'Falta Imagen');
                return;
            end
            
            app.EstadodelaimagenLabel.Text = 'Estado: Atenuando arrugas y pliegues...';
            drawnow;
            
            % 1. Obtener imagen actual en proceso
            img = app.ImagenProcesada;
            
            % 2. Filtrado selectivo para arrugas/pliegues sin destruir detalles faciales
            se = strel('disk', 2); 
            
            if size(img, 3) == 3
                imgRestaurada = img;
                for c = 1:3
                    temp = imclose(img(:,:,c), se);
                    imgRestaurada(:,:,c) = medfilt2(temp, [3 3]);
                end
            else
                temp = imclose(img, se);
                imgRestaurada = medfilt2(temp, [3 3]);
            end
            
            % 3. Guardar para operaciones sucesivas
            app.ImagenProcesada = imgRestaurada;
            
            % 4. Mostrar la imagen restaurada en UIAxes_2
            imshow(imgRestaurada, 'Parent', app.UIAxes_2); 
            
            % 5. Dibujar Histograma Original en UIAxes_3 (~ en lugar de !)
            if ~isempty(app.ImagenOriginal)
                if size(app.ImagenOriginal, 3) == 3
                    grisOrig = rgb2gray(app.ImagenOriginal);
                else
                    grisOrig = app.ImagenOriginal;
                end
                [countsOrig, binLocOrig] = imhist(grisOrig);
                bar(app.UIAxes_3, binLocOrig, countsOrig, 'BarWidth', 1, 'FaceColor', [0.3 0.3 0.3], 'EdgeColor', 'none');
                app.UIAxes_3.XLim = [0 255];
                app.UIAxes_3.YColor = [0.2 0.2 0.2];
                app.UIAxes_3.XColor = [0.2 0.2 0.2];
            end
            
            % 6. Dibujar Histograma Restaurado en UIAxes_4
            if size(imgRestaurada, 3) == 3
                grisHist = rgb2gray(imgRestaurada);
            else
                grisHist = imgRestaurada;
            end
            
            [counts, binLocations] = imhist(grisHist);
            bar(app.UIAxes_4, binLocations, counts, 'BarWidth', 1, 'FaceColor', [0.3 0.3 0.3], 'EdgeColor', 'none');
            app.UIAxes_4.XLim = [0 255];
            app.UIAxes_4.YColor = [0.2 0.2 0.2];
            app.UIAxes_4.XColor = [0.2 0.2 0.2];
            
            app.guardarEstado(imgRestaurada);
            app.actualizarInterfazProcesada();
            app.EstadodelaimagenLabel.Text = 'Estado: Arrugas y pliegues procesados.';
        end

        % Button pushed function: DegradacinButton
        function DegradacinButtonPushed(app, event)

           if isempty(app.ImagenOriginal) && isempty(app.ImagenProcesada)
            uialert(app.UIFigure, 'Por favor, importe una imagen primero.', 'Falta Imagen');
            return;
           end
        
            app.EstadodelaimagenLabel.Text = 'Estado: Restaurando imagen degradada por el sol...';
            drawnow;
        
            try
                if ~isempty(app.ImagenProcesada)
                    imgBase = app.ImagenProcesada;
                else
                    imgBase = app.ImagenOriginal;
                end
        
                % 1. Restauración HSV / CLAHE
                if size(imgBase, 3) == 3
                    hsvImg = rgb2hsv(imgBase);
                    hsvImg(:,:,2) = min(hsvImg(:,:,2) * 1.3, 1.0); 
                    hsvImg(:,:,3) = adapthisteq(hsvImg(:,:,3), 'ClipLimit', 0.02);
                    imgRestaurada = hsv2rgb(hsvImg);
                else
                    imgRestaurada = adapthisteq(imgBase, 'ClipLimit', 0.02);
                end
        
                % Filtro gaussiano suave
                imgRestaurada = imgaussfilt(imgRestaurada, 0.6);
                imgRestaurada = im2uint8(imgRestaurada);
        
                % 2. Actualizar propiedad en App Designer
                app.ImagenProcesada = imgRestaurada;
        
                % 3. Mostrar la imagen procesada
                imshow(imgRestaurada, 'Parent', app.UIAxes_2); 
        
                % 4. Renderizar Histograma Restaurado en UIAxes_4
                if size(imgRestaurada, 3) == 3
                    grisHist = rgb2gray(imgRestaurada);
                else
                    grisHist = imgRestaurada;
                end
                
                [counts, binLocations] = imhist(grisHist);
                bar(app.UIAxes_4, binLocations, counts, 'BarWidth', 1, 'FaceColor', [0 0.4470 0.7410], 'EdgeColor', 'none');
                app.UIAxes_4.XLim = [0 255];
                app.UIAxes_4.YColor = [0.9 0.9 0.9];
                app.UIAxes_4.XColor = [0.9 0.9 0.9];
                
                % 5. Renderizar Histograma Original en UIAxes_3 si aplica
                if size(app.ImagenOriginal, 3) == 3
                    grisOrig = rgb2gray(app.ImagenOriginal);
                else
                    grisOrig = app.ImagenOriginal;
                end
                [countsOrig, binLocOrig] = imhist(grisOrig);
                bar(app.UIAxes_3, binLocOrig, countsOrig, 'BarWidth', 1, 'FaceColor', [0 0.4470 0.7410], 'EdgeColor', 'none');
                app.UIAxes_3.XLim = [0 255];
                app.UIAxes_3.YColor = [0.9 0.9 0.9];
                app.UIAxes_3.XColor = [0.9 0.9 0.9];
                
                app.guardarEstado(imgRestaurada);
                app.actualizarInterfazProcesada();
                app.EstadodelaimagenLabel.Text = 'Estado: Restauración por degradación solar completada.';
        
            catch ME
                app.EstadodelaimagenLabel.Text = 'Estado: Error al aplicar la restauración por degradación.';
            end
        end

        % Button pushed function: DeshacerButton
        function DeshacerButtonPushed(app, event)
            if app.IndiceHistorial > 1
                % Retroceder un estado en el historial
                app.IndiceHistorial = app.IndiceHistorial - 1;
                app.ImagenProcesada = app.HistorialImagenes{app.IndiceHistorial};

                % Actualizar la imagen y el histograma UIAxes_4
                app.actualizarInterfazProcesada();

                app.EstadodelaimagenLabel.Text = sprintf('Estado: Operación deshecha (%d/%d).', ...
                    app.IndiceHistorial, length(app.HistorialImagenes));
            else
                uialert(app.UIFigure, 'No hay más operaciones para deshacer.', 'Historial Vacío');
            end
        end

        % Button pushed function: RehacerButton
        function RehacerButtonPushed(app, event)

                if app.IndiceHistorial < length(app.HistorialImagenes)
                    % Avanzar un estado en el historial
                    app.IndiceHistorial = app.IndiceHistorial + 1;
                    app.ImagenProcesada = app.HistorialImagenes{app.IndiceHistorial};

                    % Actualizar la imagen y el histograma UIAxes_4
                    app.actualizarInterfazProcesada();

                    app.EstadodelaimagenLabel.Text = sprintf('Estado: Operación rehecha (%d/%d).', ...
                        app.IndiceHistorial, length(app.HistorialImagenes));
                else
                    uialert(app.UIFigure, 'No hay más operaciones para rehacer.', 'Fin del Historial');
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.Panel);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes = uiaxes(app.Panel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_3 = uiaxes(app.Panel);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.Panel);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.OriginalLabel = uilabel(app.Panel);
            app.OriginalLabel.Text = 'Original';
            app.ImagenRestauradaLabel = uilabel(app.Panel);
            app.ImagenRestauradaLabel.Text = 'Imagen Restaurada';
            app.Panel_2 = uipanel(app.Panel);
            app.RayonesButton = uibutton(app.Panel_2, 'push');
            app.RayonesButton.ButtonPushedFcn = createCallbackFcn(app, @RayonesButtonPushed, true);
            app.RayonesButton.Text = 'Rayones';
            app.ArrugasPlieguesButton = uibutton(app.Panel_2, 'push');
            app.ArrugasPlieguesButton.ButtonPushedFcn = createCallbackFcn(app, @ArrugasPlieguesButtonPushed, true);
            app.ArrugasPlieguesButton.Text = 'Arrugas / Pliegues';
            app.ManchasButton = uibutton(app.Panel_2, 'push');
            app.ManchasButton.Text = 'Manchas';
            app.ReconstruccinButton = uibutton(app.Panel_2, 'push');
            app.ReconstruccinButton.Text = 'Reconstrucción';
            app.DegradacinButton = uibutton(app.Panel_2, 'push');
            app.DegradacinButton.ButtonPushedFcn = createCallbackFcn(app, @DegradacinButtonPushed, true);
            app.DegradacinButton.Text = 'Degradación';
            app.AdicindeColorButton = uibutton(app.Panel_2, 'push');
            app.AdicindeColorButton.Text = 'Adición de Color';
            app.HerramientasdeRestauracinLabel = uilabel(app.Panel_2);
            app.HerramientasdeRestauracinLabel.Text = ' Herramientas de Restauración';
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'Restaura hoy, conserva siempre ';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = 'Inicio';
            app.ImportarButton = uibutton(app.Panel, 'push');
            app.ImportarButton.ButtonPushedFcn = createCallbackFcn(app, @ImportarButtonPushed, true);
            app.ImportarButton.Tooltip = {'PNG, JPG/JPEG, TIFF y BMP'};
            app.ImportarButton.Text = 'Importar';
            app.ExportarButton = uibutton(app.Panel, 'push');
            app.ExportarButton.Text = 'Exportar';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = 'Histograma';
            app.DeshacerButton = uibutton(app.Panel, 'push');
            app.DeshacerButton.ButtonPushedFcn = createCallbackFcn(app, @DeshacerButtonPushed, true);
            app.DeshacerButton.Tooltip = {'PNG, JPG/JPEG, TIFF y BMP'};
            app.DeshacerButton.Text = 'Deshacer';
            app.RehacerButton = uibutton(app.Panel, 'push');
            app.RehacerButton.ButtonPushedFcn = createCallbackFcn(app, @RehacerButtonPushed, true);
            app.RehacerButton.Tooltip = {'PNG, JPG/JPEG, TIFF y BMP'};
            app.RehacerButton.Text = 'Rehacer';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Recursos', 'logofondo.png');
            app.EstadodelaimagenLabel = uilabel(app.UIFigure);
            app.EstadodelaimagenLabel.Text = ' Estado de la imagen';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Recursos', '2.png');
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'Recursos', '4.png');
            app.Image_4 = uiimage(app.UIFigure);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'Recursos', '3.png');
            app.InicioButton = uibutton(app.UIFigure, 'push');
            app.InicioButton.Text = 'Inicio';
            app.HistorialButton = uibutton(app.UIFigure, 'push');
            app.HistorialButton.Text = 'Historial';
            app.AyudaButton = uibutton(app.UIFigure, 'push');
            app.AyudaButton.Text = 'Ayuda';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end